classdef CO2_SerialPort < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        CheckBox_Ref          matlab.ui.control.CheckBox
        CheckBox_Act          matlab.ui.control.CheckBox
        CheckBox_Temperature  matlab.ui.control.CheckBox
        CheckBox_Common       matlab.ui.control.CheckBox
        CheckBox_Sub          matlab.ui.control.CheckBox
        CheckBox_Div          matlab.ui.control.CheckBox
        UIAxes                matlab.ui.control.UIAxes
        StartButton           matlab.ui.control.StateButton
        Label                 matlab.ui.control.Label
        DropDown_SerialPort   matlab.ui.control.DropDown
        DropDown_2Label       matlab.ui.control.Label
        DropDown_Baudrate     matlab.ui.control.DropDown
        Lamp                  matlab.ui.control.Lamp
        Label_Tip             matlab.ui.control.Label
    end


    properties (Access = private)
        SerialPort % Description
        Baudrate
        SerialHandle
    end

    properties (Access = public)
    end

    methods (Access = private)
    
        function SerialCallback(app, ~)
            out = fscanf(app.SerialHandle);
            app.Label_Tip.Text = out;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           SYS_Ports = instrhwinfo('Serial');
           SYS_AvailablePorts = SYS_Ports.AvailableSerialPorts;
           app.DropDown_SerialPort.Items = [SYS_AvailablePorts' 'Refresh'];
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            try
                switch(value)
                    case 0   %弹起
                        fclose(app.SerialHandle);
                        app.Lamp.Enable =  'off';
                    case 1   %按下
                        app.SerialHandle = serial(app.SerialPort);
                        app.SerialHandle.BaudRate = str2double(app.DropDown_Baudrate.Value);
                        app.SerialHandle.Terminator = 'LF';
                        app.SerialHandle.BytesAvailableFcnMode = 'Terminator';
                        app.SerialHandle.BytesAvailableFcn = createCallbackFcn(app, @SerialCallback, true);
                        fopen(app.SerialHandle);
                        
                        app.Lamp.Enable =  'on';
                end
            catch ex
                msgbox(ex.message)
            end
        end

        % Value changed function: DropDown_SerialPort
        function DropDown_SerialPortValueChanged(app, event)
            value = app.DropDown_SerialPort.Value;
            app.SerialPort = value;
        end

        % Value changed function: DropDown_Baudrate
        function DropDown_BaudrateValueChanged(app, event)
            value = app.DropDown_Baudrate.Value;
            app.Baudrate = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CheckBox_Ref = uicheckbox(app.UIFigure);
            app.CheckBox_Ref.Text = '参考通道';
            app.CheckBox_Act = uicheckbox(app.UIFigure);
            app.CheckBox_Act.Text = '工作通道';
            app.CheckBox_Temperature = uicheckbox(app.UIFigure);
            app.CheckBox_Temperature.Text = '温度通道';
            app.CheckBox_Common = uicheckbox(app.UIFigure);
            app.CheckBox_Common.Text = '公共通道';
            app.CheckBox_Sub = uicheckbox(app.UIFigure);
            app.CheckBox_Sub.Text = '差值通道';
            app.CheckBox_Div = uicheckbox(app.UIFigure);
            app.CheckBox_Div.Text = '商值通道';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '端口号';
            app.DropDown_SerialPort = uidropdown(app.UIFigure);
            app.DropDown_SerialPort.Items = {};
            app.DropDown_SerialPort.ValueChangedFcn = createCallbackFcn(app, @DropDown_SerialPortValueChanged, true);
            app.DropDown_SerialPort.Value = {};
            app.DropDown_2Label = uilabel(app.UIFigure);
            app.DropDown_2Label.Text = '波特率';
            app.DropDown_Baudrate = uidropdown(app.UIFigure);
            app.DropDown_Baudrate.Items = {'9600', '19200', '115200'};
            app.DropDown_Baudrate.ValueChangedFcn = createCallbackFcn(app, @DropDown_BaudrateValueChanged, true);
            app.DropDown_Baudrate.Value = '115200';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Enable = 'off';
            app.Label_Tip = uilabel(app.UIFigure);
            app.Label_Tip.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CO2_SerialPort

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end